classdef RobotABB_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Axis1Panel          matlab.ui.container.Panel
        Label_7             matlab.ui.control.Label
        ValueLabel_7        matlab.ui.control.Label
        Slider_7            matlab.ui.control.Slider
        Label_6             matlab.ui.control.Label
        ValueLabel_6        matlab.ui.control.Label
        Slider_6            matlab.ui.control.Slider
        Label_5             matlab.ui.control.Label
        ValueLabel_5        matlab.ui.control.Label
        Slider_5            matlab.ui.control.Slider
        Label_3             matlab.ui.control.Label
        ValueLabel_3        matlab.ui.control.Label
        Slider_3            matlab.ui.control.Slider
        Label_2             matlab.ui.control.Label
        ValueLabel_2        matlab.ui.control.Label
        Slider_2            matlab.ui.control.Slider
        Label               matlab.ui.control.Label
        ValueLabel          matlab.ui.control.Label
        Slider              matlab.ui.control.Slider
        RobotDKPanel        matlab.ui.container.Panel
        ConnectCheckBox_2   matlab.ui.control.CheckBox
        NameEditField       matlab.ui.control.EditField
        NameEditFieldLabel  matlab.ui.control.Label
        uipanel2            matlab.ui.container.Panel
        AttachCheckBox      matlab.ui.control.CheckBox
        PORTDropDown        matlab.ui.control.DropDown
        PORTDropDownLabel   matlab.ui.control.Label
        UIAxes2             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        t1 = 0 % Description
        t2 = 0  % Description
        t3 = 0 % Description
        t4 = 0 % Description
        t5 = 0  % Description
        t6 = 0 % Description
    end
    
    methods (Access = private)
        
        function initArduino(app)
            freeports = serialportlist("available");
            app.PORTDropDown.Items = freeports;
        end
        
        function results = RobotDKConnect(app,name)
            
            RDK=Robolink;
            robot=RDK.Item(name);
            
        end
        
        function enableSliders(app,enabled)
            app.Slider.Enable = enabled;
            app.Slider_2.Enable = enabled;
            app.Slider_3.Enable = enabled;
            app.Slider_5.Enable = enabled;
            app.Slider_6.Enable = enabled;
            app.Slider_7.Enable = enabled;
        end
        
        function results = RobotFunc(app)
            clf
           
           [T06] = ABB(app.t1,app.t2,app.t3,app.t4,app.t5,app.t6,0)
        
        
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initArduino(app);
            global ard;
            global RDK;
            global robot;
           
            RobotFunc(app)


        end

        % Drop down opening function: PORTDropDown
        function PORTDropDownOpening(app, event)
       
        end

        % Value changed function: AttachCheckBox
        function AttachCheckBoxValueChanged(app, event)
            value = app.AttachCheckBox.Value;
            app.PORTDropDown.Enable = ~value;       
            
            if value == 1
                 ard = arduino(app.PORTDropDown.Value,"Uno");
                app.PORTDropDown.BackgroundColor = 'g';
                enableSliders(app,false);
                   
            while value == 1
                app.t1=floor((360*readVoltage(ard,'A0')/5)-180);
                   app.t2=floor((360*readVoltage(ard,'A1')/5)-180);
                   app.t3=floor((360*readVoltage(ard,'A2')/5)-180);
                   cla
                [T06] = UR5(app.t1,app.t2,app.t3,app.t4,app.t5,app.t6)
                    
                    app.Slider.Value = app.t1;
                    app.Slider_2.Value = app.t2;
                    app.Slider_3.Value = app.t3;
                    app.Slider_5.Value = app.t4;
                    app.Slider_6.Value = app.t5;
                    app.Slider_7.Value = app.t6;
                    
                    app.Label.Text = num2str(floor(app.t1));
                    app.Label_2.Text = num2str(floor(app.t2));
                    app.Label_3.Text = num2str(floor(app.t3));
                    app.Label_5.Text = num2str(floor(app.t4));
                    app.Label_6.Text = num2str(floor(app.t5));
                    app.Label_7.Text = num2str(floor(app.t6));
                        
            end
             clear a;
            else
                clear a;
                app.PORTDropDown.BackgroundColor = [0.96,0.96,0.96];
                enableSliders(app,true);
            end


        end

        % Value changed function: ConnectCheckBox_2
        function ConnectCheckBox_2ValueChanged(app, event)
            value = app.ConnectCheckBox_2.Value;
            app.NameEditField.Enable = ~value;
            if value == 1
            RobotDKConnect(app,app.NameEditField.Value);
            else
                clear RDK robot
            end
            
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Label.Text = num2str(floor(changingValue));
            app.t1=changingValue;
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.Label_2.Text = num2str(floor(changingValue));
            app.t2=changingValue;
        end

        % Value changing function: Slider_3
        function Slider_3ValueChanging(app, event)
            changingValue = event.Value;
            app.Label_3.Text = num2str(floor(changingValue));
            app.t3=changingValue;
        end

        % Value changing function: Slider_5
        function Slider_5ValueChanging(app, event)
            changingValue = event.Value;
            app.Label_5.Text = num2str(floor(changingValue));
            app.t4=changingValue;
        end

        % Value changing function: Slider_6
        function Slider_6ValueChanging(app, event)
            changingValue = event.Value;
            app.Label_6.Text = num2str(floor(changingValue));
            app.t5=changingValue;
        end

        % Value changing function: Slider_7
        function Slider_7ValueChanging(app, event)
            changingValue = event.Value;
            app.Label_7.Text = num2str(floor(changingValue));
            app.t6=changingValue;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            RobotFunc(app)
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            RobotFunc(app)
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            value = app.Slider_3.Value;
            RobotFunc(app)
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            value = app.Slider_5.Value;
            RobotFunc(app)
        end

        % Value changed function: Slider_7
        function Slider_7ValueChanged(app, event)
            value = app.Slider_7.Value;
            RobotFunc(app)
        end

        % Value changed function: Slider_6
        function Slider_6ValueChanged(app, event)
            value = app.Slider_6.Value;
            RobotFunc(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.uipanel2 = uipanel(app.UIFigure);
            app.uipanel2.Title = 'Arduino Remote Control:';
            app.uipanel2.Tag = 'uipanel2';
            app.PORTDropDownLabel = uilabel(app.uipanel2);
            app.PORTDropDownLabel.Text = 'PORT';
            app.PORTDropDown = uidropdown(app.uipanel2);
            app.PORTDropDown.Editable = 'on';
            app.PORTDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PORTDropDownOpening, true);
            app.PORTDropDown.Placeholder = 'Select';
            app.AttachCheckBox = uicheckbox(app.uipanel2);
            app.AttachCheckBox.ValueChangedFcn = createCallbackFcn(app, @AttachCheckBoxValueChanged, true);
            app.AttachCheckBox.Tag = 'Arduino_Connect';
            app.AttachCheckBox.Text = 'Attach';
            app.RobotDKPanel = uipanel(app.UIFigure);
            app.RobotDKPanel.Title = 'RobotDK:';
            app.NameEditFieldLabel = uilabel(app.RobotDKPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.RobotDKPanel, 'text');
            app.ConnectCheckBox_2 = uicheckbox(app.RobotDKPanel);
            app.ConnectCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ConnectCheckBox_2ValueChanged, true);
            app.ConnectCheckBox_2.Text = 'Connect';
            app.Axis1Panel = uipanel(app.UIFigure);
            app.Axis1Panel.Title = 'Axis 1';
            app.Slider = uislider(app.Axis1Panel);
            app.Slider.Limits = [-180 180];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.ValueLabel = uilabel(app.Axis1Panel);
            app.ValueLabel.Text = 'Value:';
            app.Label = uilabel(app.Axis1Panel);
            app.Label.Text = '0';
            app.Slider_2 = uislider(app.Axis1Panel);
            app.Slider_2.Limits = [-180 180];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.ValueLabel_2 = uilabel(app.Axis1Panel);
            app.ValueLabel_2.Text = 'Value:';
            app.Label_2 = uilabel(app.Axis1Panel);
            app.Label_2.Text = '0';
            app.Slider_3 = uislider(app.Axis1Panel);
            app.Slider_3.Limits = [-180 180];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Slider_3ValueChanging, true);
            app.ValueLabel_3 = uilabel(app.Axis1Panel);
            app.ValueLabel_3.Text = 'Value:';
            app.Label_3 = uilabel(app.Axis1Panel);
            app.Label_3.Text = '0';
            app.Slider_5 = uislider(app.Axis1Panel);
            app.Slider_5.Limits = [-180 180];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Slider_5.ValueChangingFcn = createCallbackFcn(app, @Slider_5ValueChanging, true);
            app.ValueLabel_5 = uilabel(app.Axis1Panel);
            app.ValueLabel_5.Text = 'Value:';
            app.Label_5 = uilabel(app.Axis1Panel);
            app.Label_5.Text = '0';
            app.Slider_6 = uislider(app.Axis1Panel);
            app.Slider_6.Limits = [-180 180];
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @Slider_6ValueChanged, true);
            app.Slider_6.ValueChangingFcn = createCallbackFcn(app, @Slider_6ValueChanging, true);
            app.ValueLabel_6 = uilabel(app.Axis1Panel);
            app.ValueLabel_6.Text = 'Value:';
            app.Label_6 = uilabel(app.Axis1Panel);
            app.Label_6.Text = '0';
            app.Slider_7 = uislider(app.Axis1Panel);
            app.Slider_7.Limits = [-180 180];
            app.Slider_7.ValueChangedFcn = createCallbackFcn(app, @Slider_7ValueChanged, true);
            app.Slider_7.ValueChangingFcn = createCallbackFcn(app, @Slider_7ValueChanging, true);
            app.ValueLabel_7 = uilabel(app.Axis1Panel);
            app.ValueLabel_7.Text = 'Value:';
            app.Label_7 = uilabel(app.Axis1Panel);
            app.Label_7.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotABB_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end