classdef RobotSCARA_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1    matlab.ui.Figure
        Button1    matlab.ui.control.Button
        uipanel2   matlab.ui.container.Panel
        text8      matlab.ui.control.Label
        checkbox2  matlab.ui.control.CheckBox
        text7      matlab.ui.control.Label
        checkbox1  matlab.ui.control.CheckBox
        text6      matlab.ui.control.Label
        slider4    matlab.ui.control.Slider
        text4      matlab.ui.control.Label
        text3      matlab.ui.control.Label
        text2      matlab.ui.control.Label
        slider3    matlab.ui.control.Slider
        slider2    matlab.ui.control.Slider
        slider1    matlab.ui.control.Slider
        axes1      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function RobotSCARA_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to RobotSCARA (see VARARGIN)
            
            % Choose default command line output for RobotSCARA
            handles.output = hObject;
            global RDK;
            global robot;
            RDK=Robolink;
            robot=RDK.Item('Adept');
            
            
            global a;
            
            a = arduino('COM4','Uno')
            t1 = 0;
            t2 = 0;
            d1 = 0;
            t3 = 0;
            handles.t1=t1;
            handles.t2=t2;
            handles.t3=t3;
            handles.d1=d1;
            set(handles.text2,'String',t1)
            set(handles.text3,'String',t2)
            set(handles.text4,'String',t3)
            set(handles.text6,'String',d1)
               [T04]=SCARA(t1,t2,d1,t3)
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global a;
            check_Box_Value = get(hObject,'Value');
            while check_Box_Value==1
               check_Box_Value = get(hObject,'Value');
               t1=(360*readVoltage(a,'A0')/5)-180;
               t2=(360*readVoltage(a,'A1')/5)-180;
               t3=(360*readVoltage(a,'A2')/5)-180;
               cla
               [T03]=RRR(t1,t2,t3);
                handles.t1 = t1;
                handles.t2 = t2;
                handles.t3 = t3;
                set(handles.slider1,'Value',t1)
                set(handles.slider2,'Value',t2)
                set(handles.slider3,'Value',t3)
            
                set(handles.text2,'String',t1)
                set(handles.text3,'String',t2)
                set(handles.text4,'String',t3)
                   if check_Box_Value==0
                    break
                   end
            end
                guidata(hObject,handles)
        end

        % Value changed function: checkbox2
        function checkbox2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            check_Box_Value = get(hObject,'Value');
        end

        % Value changed function: slider1
        function slider1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global RDK;
            global robot;
            global a;
            t1 = get(hObject,'Value');
            t2 =handles.t2;
            t3 =handles.t3;
            d1=handles.d1;
            cla
            [T04]=SCARA(t1,t2,d1,t3)
            handles.t1 = t1;
            handles.t2 = t2;
            handles.t3 = t3;
            handles.d1=d1;
            set(handles.text2,'String',t1)
            set(handles.text3,'String',t2)
            set(handles.text4,'String',t3)
            set(handles.text6,'String',d1)
            
            robot.setJoints([t1 t2 d1 t3])
            
            
            guidata(hObject,handles)
        end

        % Value changed function: slider2
        function slider2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global RDK;
            global robot;
            global a;
            t1 = handles.t1;
            t2 =get(hObject,'Value');
            t3 =handles.t3;
            d1=handles.d1;
            cla
            [T04]=SCARA(t1,t2,d1,t3)
            handles.t1 = t1;
            handles.t2 = t2;
            handles.t3 = t3;
            handles.d1=d1;
            set(handles.text2,'String',t1)
            set(handles.text3,'String',t2)
            set(handles.text4,'String',t3)
            set(handles.text6,'String',d1)
            
            
              robot.setJoints([t1 t2 d1 t3])
            
            guidata(hObject,handles)
        end

        % Value changed function: slider3
        function slider3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global RDK;
            global robot;
            global a;
            t1 = handles.t1;
            t2 = handles.t2;
            t3 =get(hObject,'Value');
            d1=handles.d1;
            cla
            [T04]=SCARA(t1,t2,d1,t3)
            handles.t1 = t1;
            handles.t2 = t2;
            handles.t3 = t3;
            handles.d1=d1;
            set(handles.text2,'String',t1)
            set(handles.text3,'String',t2)
            set(handles.text4,'String',t3)
            set(handles.text6,'String',d1)
            
            
              robot.setJoints([t1 t2 d1 t3])
            
            guidata(hObject,handles)
        end

        % Value changed function: slider4
        function slider4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            global RDK;
            global robot;
            global a;
            t1 = handles.t1;
            t2 = handles.t2;
            t3 =handles.t3;
            d1=get(hObject,'Value');
            cla
            [T04]=SCARA(t1,t2,d1,t3)
            handles.t1 = t1;
            handles.t2 = t2;
            handles.t3 = t3;
            handles.d1=d1;
            set(handles.text2,'String',t1)
            set(handles.text3,'String',t2)
            set(handles.text4,'String',t3)
            set(handles.text6,'String',d1)
            
            
              robot.setJoints([t1 t2 d1 t3])
            
            guidata(hObject,handles)
        end

        % Button pushed function: Button1
        function Button1Pushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'RobotSCARA';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.slider1 = uislider(app.figure1);
            app.slider1.Limits = [-180 180];
            app.slider1.MajorTicks = [];
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @slider1_Callback, true);
            app.slider1.MinorTicks = [];
            app.slider1.Tag = 'slider1';
            app.slider2 = uislider(app.figure1);
            app.slider2.Limits = [-180 180];
            app.slider2.MajorTicks = [];
            app.slider2.ValueChangedFcn = createCallbackFcn(app, @slider2_Callback, true);
            app.slider2.MinorTicks = [];
            app.slider2.Tag = 'slider2';
            app.slider3 = uislider(app.figure1);
            app.slider3.Limits = [-180 180];
            app.slider3.MajorTicks = [];
            app.slider3.ValueChangedFcn = createCallbackFcn(app, @slider3_Callback, true);
            app.slider3.MinorTicks = [];
            app.slider3.Tag = 'slider3';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Static Text';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Static Text';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Static Text';
            app.slider4 = uislider(app.figure1);
            app.slider4.Limits = [0 180];
            app.slider4.MajorTicks = [];
            app.slider4.ValueChangedFcn = createCallbackFcn(app, @slider4_Callback, true);
            app.slider4.MinorTicks = [];
            app.slider4.Tag = 'slider4';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Static Text';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Remote Control:';
            app.uipanel2.Tag = 'uipanel2';
            app.checkbox1 = uicheckbox(app.uipanel2);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Tag = 'checkbox1';
            app.checkbox1.Text = 'Enable';
            app.text7 = uilabel(app.uipanel2);
            app.text7.Tag = 'text7';
            app.text7.Text = 'RobotDK:';
            app.checkbox2 = uicheckbox(app.uipanel2);
            app.checkbox2.ValueChangedFcn = createCallbackFcn(app, @checkbox2_Callback, true);
            app.checkbox2.Tag = 'checkbox2';
            app.checkbox2.Text = 'Attatch';
            app.text8 = uilabel(app.uipanel2);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Arduino:';
            app.Button1 = uibutton(app.figure1, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Text = 'Button1';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotSCARA_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)RobotSCARA_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end