classdef RobotUR5_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1             matlab.ui.Figure
        uipanel2            matlab.ui.container.Panel
        AttachCheckBox      matlab.ui.control.CheckBox
        NameEditField       matlab.ui.control.EditField
        NameEditFieldLabel  matlab.ui.control.Label
        text8               matlab.ui.control.Label
        text7               matlab.ui.control.Label
        checkbox1           matlab.ui.control.CheckBox
        text6               matlab.ui.control.Label
        slider4             matlab.ui.control.Slider
        text4               matlab.ui.control.Label
        text3               matlab.ui.control.Label
        text2               matlab.ui.control.Label
        slider3             matlab.ui.control.Slider
        slider2             matlab.ui.control.Slider
        slider1             matlab.ui.control.Slider
        axes1               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function RobotUR5_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to RobotUR5 (see VARARGIN)
            
            % Choose default command line output for RobotUR5
             app.Panel.Visible = 'off';
            
            
            handles.output = hObject;
           
            global RDK;
            global robot;
            global robotName;
            
            
            global a;
            
            a = arduino('COM4','Uno')


            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
         
            
            guidata(hObject,handles)
        end

        % Callback function
        function checkbox2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            check_Box_Value = get(hObject,'Value');
        end

        % Value changed function: slider1
        function slider1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)


            guidata(hObject,handles)
        end

        % Value changed function: slider2
        function slider2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
    
            
            guidata(hObject,handles)
        end

        % Value changed function: slider3
        function slider3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
      
            guidata(hObject,handles)
        end

        % Value changed function: slider4
        function slider4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slider4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
       
            guidata(hObject,handles)
        end

        % Value changed function: AttachCheckBox
        function AttachCheckBoxValueChanged(app, event)
            value = app.AttachCheckBox.Value;
                app.NameEditField.Editable = ~value;
           
            RDK=Robolink;
            robot=RDK.Item(robotName);
            
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            value = app.NameEditField.Value;
            robotName = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'RobotUR5';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.slider1 = uislider(app.figure1);
            app.slider1.Limits = [-180 180];
            app.slider1.MajorTicks = [];
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @slider1_Callback, true);
            app.slider1.MinorTicks = [];
            app.slider1.Tag = 'slider1';
            app.slider2 = uislider(app.figure1);
            app.slider2.Limits = [-180 180];
            app.slider2.MajorTicks = [];
            app.slider2.ValueChangedFcn = createCallbackFcn(app, @slider2_Callback, true);
            app.slider2.MinorTicks = [];
            app.slider2.Tag = 'slider2';
            app.slider3 = uislider(app.figure1);
            app.slider3.Limits = [-180 180];
            app.slider3.MajorTicks = [];
            app.slider3.ValueChangedFcn = createCallbackFcn(app, @slider3_Callback, true);
            app.slider3.MinorTicks = [];
            app.slider3.Tag = 'slider3';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Static Text';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Static Text';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Static Text';
            app.slider4 = uislider(app.figure1);
            app.slider4.Limits = [0 180];
            app.slider4.MajorTicks = [];
            app.slider4.ValueChangedFcn = createCallbackFcn(app, @slider4_Callback, true);
            app.slider4.MinorTicks = [];
            app.slider4.Tag = 'slider4';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Static Text';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Remote Control:';
            app.uipanel2.Tag = 'uipanel2';
            app.checkbox1 = uicheckbox(app.uipanel2);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Tag = 'checkbox1';
            app.checkbox1.Text = 'Enable';
            app.text7 = uilabel(app.uipanel2);
            app.text7.Tag = 'text7';
            app.text7.Text = 'RobotDK:';
            app.text8 = uilabel(app.uipanel2);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Arduino:';
            app.NameEditFieldLabel = uilabel(app.uipanel2);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.uipanel2, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.AttachCheckBox = uicheckbox(app.uipanel2);
            app.AttachCheckBox.ValueChangedFcn = createCallbackFcn(app, @AttachCheckBoxValueChanged, true);
            app.AttachCheckBox.Text = 'Attach';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotUR5_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)RobotUR5_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end