classdef RobotUR5_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        RobotDKPanel             matlab.ui.container.Panel
        SetPoseButton            matlab.ui.control.Button
        ReadPoseButton           matlab.ui.control.Button
        Switch                   matlab.ui.control.Switch
        Lamp_3                   matlab.ui.control.Lamp
        RobotnameEditField       matlab.ui.control.EditField
        RobotnameEditFieldLabel  matlab.ui.control.Label
        Panel                    matlab.ui.container.Panel
        Label_17                 matlab.ui.control.Label
        Label_16                 matlab.ui.control.Label
        Label_15                 matlab.ui.control.Label
        Label_14                 matlab.ui.control.Label
        Y2Label_2                matlab.ui.control.Label
        X2Label_2                matlab.ui.control.Label
        Y1Label_2                matlab.ui.control.Label
        X1Label_2                matlab.ui.control.Label
        TabGroup                 matlab.ui.container.TabGroup
        ArduinoTab               matlab.ui.container.Tab
        SettingsPanel            matlab.ui.container.Panel
        DropDown_4               matlab.ui.control.DropDown
        DropDown_3               matlab.ui.control.DropDown
        DropDown_2               matlab.ui.control.DropDown
        Y2Label                  matlab.ui.control.Label
        X2Label                  matlab.ui.control.Label
        Y1Label                  matlab.ui.control.Label
        X1Label                  matlab.ui.control.Label
        DropDown                 matlab.ui.control.DropDown
        TraveltypeButtonGroup    matlab.ui.container.ButtonGroup
        ToolframeButton          matlab.ui.control.RadioButton
        RobotbaseButton          matlab.ui.control.RadioButton
        JointbyjointButton       matlab.ui.control.RadioButton
        Lamp                     matlab.ui.control.Lamp
        AttachCheckBox           matlab.ui.control.CheckBox
        UpdateButton             matlab.ui.control.Button
        PORTDropDown             matlab.ui.control.DropDown
        XboxTab                  matlab.ui.container.Tab
        Lamp_2                   matlab.ui.control.Lamp
        TablePanel               matlab.ui.container.Panel
        Panel_2                  matlab.ui.container.Panel
        Button_2                 matlab.ui.control.Button
        Button                   matlab.ui.control.Button
        Label_29                 matlab.ui.control.Label
        Label_28                 matlab.ui.control.Label
        Label_27                 matlab.ui.control.Label
        Label_26                 matlab.ui.control.Label
        Label_25                 matlab.ui.control.Label
        Label_24                 matlab.ui.control.Label
        Label_23                 matlab.ui.control.Label
        Label_22                 matlab.ui.control.Label
        Label_21                 matlab.ui.control.Label
        Label_20                 matlab.ui.control.Label
        Label_19                 matlab.ui.control.Label
        Label_18                 matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
        dViewPanel               matlab.ui.container.Panel
        LimitsSlider             matlab.ui.control.Slider
        LimitsSliderLabel        matlab.ui.control.Label
        UIAxes2                  matlab.ui.control.UIAxes
        Axis1Panel               matlab.ui.container.Panel
        EditField_6              matlab.ui.control.NumericEditField
        EditField_5              matlab.ui.control.NumericEditField
        EditField_4              matlab.ui.control.NumericEditField
        EditField_3              matlab.ui.control.NumericEditField
        EditField_2              matlab.ui.control.NumericEditField
        EditField                matlab.ui.control.NumericEditField
        Label_13                 matlab.ui.control.Label
        Slider_7                 matlab.ui.control.Slider
        Label_12                 matlab.ui.control.Label
        Slider_6                 matlab.ui.control.Slider
        Label_11                 matlab.ui.control.Label
        Slider_5                 matlab.ui.control.Slider
        Label_10                 matlab.ui.control.Label
        Slider_3                 matlab.ui.control.Slider
        Label_9                  matlab.ui.control.Label
        Slider_2                 matlab.ui.control.Slider
        Label_8                  matlab.ui.control.Label
        Slider                   matlab.ui.control.Slider
        ContextMenu              matlab.ui.container.ContextMenu
        Menu                     matlab.ui.container.Menu
        Menu2                    matlab.ui.container.Menu
    end

    
    properties (Access = private)
        t1 = 0 % Description
        t2 = 0  % Description
        t3 = 0 % Description
        t4 = 0 % Description
        t5 = 0  % Description
        t6 = 0 % Description
        joyMiddlePosX1 = 0;
        joyMiddlePosY1 = 0;
        joyMiddlePosX2 = 0;
        joyMiddlePosY2 = 0;
        joyX1
        joyY1
        joyX2
        joyY2
        
        joyUpdateTime=0.1 % Description
        joyTimer % Description
    end
    
    methods (Access = private)
        
        function initPorts(app)
            freeports = serialportlist("available");
            app.PORTDropDown.Items = freeports;
        end
        
        function results = RobotDKConnect(app,name)
            
            RDK=Robolink;
            robot=RDK.Item(name);
            
        end
        
        function enableSliders(app,enabled)
            app.Slider.Enable = enabled;
            app.Slider_2.Enable = enabled;
            app.Slider_3.Enable = enabled;
            app.Slider_5.Enable = enabled;
            app.Slider_6.Enable = enabled;
            app.Slider_7.Enable = enabled;
        end
        
        function results = RobotFunc(app)
           
            cla(app.UIAxes2)
           app.UIFigure.HandleVisibility = 'on';
           [T06] = UR5(app.t1,app.t2,app.t3,app.t4,app.t5,app.t6)
           app.UITable.Data = T06;
            hold(app.UIAxes2); 
            setLimits(app);
            
        end


        function setLimits(app)
            limit = app.LimitsSlider.Value;
             axis(app.UIAxes2,[-limit limit -limit limit -limit limit]);
        end
        
        function joyUpdate(app)
           
            errorX1 = app.joyMiddlePosX1 - (-readVoltage(a,'A0'))/2.5;
            errorY1 = app.joyMiddlePosY1 -(readVoltage(a,'A1'))/2.5;
            
            errorX2 = app.joyMiddlePosX2 - (-readVoltage(a,'A2'))/2.5;
            errorY2 = app.joyMiddlePosY2 - (-readVoltage(a,'A3'))/2.5;
            
            app.joyX1 = app.joyX1 - errorX1;
            app.joyY1 = app.joyY1 - errorY1;
            
            app.joyX2 = app.joyX2 - errorX2;
            app.joyY2 = app.joyY2 - errorY2;
            
            if (abs(errorX1)<0.15)
                errorX1 = 0;
            end
            if (abs(errorY1)<0.15)
                errorY1 = 0;
            end
            
            if (abs(errorX2)<0.15)
                errorX2 = 0;
            end
            if (abs(errorY2)<0.15)
                errorY2 = 0;
            end
            
            
            errorX1 = floor(errorX1*5);
            errorY1 = floor(errorY1*5);
            
            errorX2 = floor(errorX2*5);
            errorY2 = floor(errorY2*5);
            
            if  errorX1 > 3
               errorX1 = 3;  
            end
            if  errorX1 < -3
                errorX1 = -3;  
            end
            
            if  errorY1 > 3
               errorY1 = 3;  
            end
            if  errorY1 < -3
                errorY1 = -3;  
            end


            if  errorX2 > 3
               errorX2 = 3;  
            end
            if  errorX2 < -3
                errorX2 = -3;  
            end
            
            if  errorY2 > 3
               errorY2 = 3;  
            end
            if  errorY2 < -3
                errorY2 = -3;  
            end
            
            app.joyX1 = app.joyX1 - errorX1;
            app.joyY1 = app.joyY1 - errorY1;
            
            app.joyX2 = app.joyX2 - errorX2;
            app.joyY2 = app.joyY2 - errorY2;
            
            
             app.X1Label.Text = num2str(app.joyX1);
             app.Label_15.Text = num2str(app.joyY1);
             app.Label_16.Text = num2str(app.joyX2);
             app.Label_17.Text = num2str(app.joyY2);
            
        end
        
        function joyInit(app)
            app.joyMiddlePosX1 = -readVoltage(a,'A0')/2.5
            app.joyMiddlePosY1 = readVoltage(a,'A1')/2.5
            app.joyMiddlePosX2 = -readVoltage(a,'A2')/2.5
            app.joyMiddlePosY2 =  readVoltage(a,'A3')/2.5
            % таймер 
            
        end
        
        function timers(app)
           app.joyTimer = timer('Period',2,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
           
           app.joyTimer.TimerFcn = @(x,y)disp('do something');
           start(app.joyTimer)
            
        end


        function OnCloseProgram(app)


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setLimits(app);
            initPorts(app);
            timers(app);
            global ard;
            global RDK;
            global robot;
           
            RobotFunc(app)


        end

        % Drop down opening function: PORTDropDown
        function PORTDropDownOpening(app, event)
       
        end

        % Callback function
        function AttachCheckBoxValueChanged(app, event)
            value = app.AttachCheckBox.Value;
            app.PORTDropDown.Enable = ~value;       
            
            if value == 1
                 ard = arduino(app.PORTDropDown.Value,"Uno");
                app.PORTDropDown.BackgroundColor = 'g';
                enableSliders(app,false);
                   
            while value == 1
                app.t1=floor((360*readVoltage(ard,'A0')/5)-180);
                   app.t2=floor((360*readVoltage(ard,'A1')/5)-180);
                   app.t3=floor((360*readVoltage(ard,'A2')/5)-180);
                   cla
                [T06] = UR5(app.t1,app.t2,app.t3,app.t4,app.t5,app.t6)
                    
                    app.Slider.Value = app.t1;
                    app.Slider_2.Value = app.t2;
                    app.Slider_3.Value = app.t3;
                    app.Slider_5.Value = app.t4;
                    app.Slider_6.Value = app.t5;
                    app.Slider_7.Value = app.t6;
                    
                    app.Label.Text = num2str(floor(app.t1));
                    app.Label_2.Text = num2str(floor(app.t2));
                    app.Label_3.Text = num2str(floor(app.t3));
                    app.Label_5.Text = num2str(floor(app.t4));
                    app.Label_6.Text = num2str(floor(app.t5));
                    app.Label_7.Text = num2str(floor(app.t6));
                        
            end
             clear a;
            else
                clear a;
                app.PORTDropDown.BackgroundColor = [0.96,0.96,0.96];
                enableSliders(app,true);
            end


        end

        % Callback function
        function ConnectCheckBox_2ValueChanged(app, event)


        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField.Value = (floor(changingValue));
         
            app.t1=changingValue;
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = event.Value;
           app.EditField_2.Value = (floor(changingValue));
            app.t2=changingValue;
        end

        % Value changing function: Slider_3
        function Slider_3ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_3.Value = (floor(changingValue));
            app.t3=changingValue;
        end

        % Value changing function: Slider_5
        function Slider_5ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_4.Value = (floor(changingValue));
            app.t4=changingValue;
        end

        % Value changing function: Slider_6
        function Slider_6ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_5.Value = (floor(changingValue));
            app.t5=changingValue;
        end

        % Value changing function: Slider_7
        function Slider_7ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_6.Value = (floor(changingValue));
            app.t6=changingValue;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            RobotFunc(app)
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            RobotFunc(app)
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            value = app.Slider_3.Value;
            RobotFunc(app)
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            value = app.Slider_5.Value;
            RobotFunc(app)
        end

        % Value changed function: Slider_7
        function Slider_7ValueChanged(app, event)
            value = app.Slider_7.Value;
            RobotFunc(app)
        end

        % Value changed function: Slider_6
        function Slider_6ValueChanged(app, event)
            value = app.Slider_6.Value;
            RobotFunc(app)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
        end

        % Value changed function: LimitsSlider
        function LimitsSliderValueChanged(app, event)
            limit = app.LimitsSlider.Value;
            axis([-limit limit -limit limit -limit limit]);
        end

        % Callback function
        function CoonectSwitchValueChanged(app, event)
            value= app.CoonectSwitch.Value;
           
            
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            initPorts(app);
        end

        % Value changed function: AttachCheckBox
        function AttachCheckBoxValueChanged2(app, event)
            value = app.AttachCheckBox.Value; 
            
           
            app.PORTDropDown.Enable = ~value;       
            
            if value == 1
                 joyInit(app)
                 ard = arduino(app.PORTDropDown.Value,"Uno");
                app.Lamp.Color = 'g';
                enableSliders(app,false);
                   
            while app.AttachCheckBox.Value == 1
                   app.t1=app.t1+floor((readVoltage(ard,'A0')/5)-2.5);
                   app.t2=app.t2+floor((readVoltage(ard,'A1')/5)-2.5);
                   app.t3=app.t3+floor((readVoltage(ard,'A2')/5)-2.5);
                   app.t4=app.t4+floor((readVoltage(ard,'A3')/5)-2.5);
                    
                   app.X1Label.Text = num2str(app.t1);
                   app.Label_15.Text = num2str(app.t2);
                   app.Label_16.Text = num2str(app.t3);
                   app.Label_17.Text = num2str(app.t4);
                   
                   RobotFunc(app)
                    
                    app.Slider.Value = app.t1;
                    app.Slider_2.Value = app.t2;
                    app.Slider_3.Value = app.t3;
                    app.Slider_5.Value = app.t4;
                    app.Slider_6.Value = app.t5;
                    app.Slider_7.Value = app.t6;
                    
                    app.EditField.Value = (floor(app.t1));
                    app.EditField_2.Value =  (floor(app.t2));
                    app.EditField_3.Value = (floor(app.t3));
                    app.EditField_4.Value = (floor(app.t4));
                    app.EditField_5.Value = (floor(app.t5));
                   app.EditField_6.Value = (floor(app.t6));


            end
             clear a;
            else
                clear a;
                app.Lamp.Color = [1.00,0,0];
                app.PORTDropDown.BackgroundColor = [0.96,0.96,0.96];
                enableSliders(app,true);
            end
         
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.Slider.Value = value;
            app.t1 = value;
            RobotFunc(app);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.Slider_2.Value = value;
            app.t2 = value;
            RobotFunc(app);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.Slider_3.Value = value;
            app.t3 = value;
            RobotFunc(app);
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            app.Slider_5.Value = value;
            app.t4 = value;
            RobotFunc(app);
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            app.Slider_6.Value = value;
            app.t5 = value;
            RobotFunc(app);
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
            app.Slider_7.Value = value;
            app.t6 = value;
            RobotFunc(app);
        end

        % Callback function
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "On"
                
                app.NameEditField.Enable = 1;
                app.ConnectCheckBox_2.Enable = 1; 
                
            else
                app.ConnectCheckBox_2.Enable = 0;
                app.NameEditField.Enable = 0;
                
            end
        end

        % Callback function
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            if value == "On"
                app.PORTDropDown.Enable = 1;
                app.AttachCheckBox.Enable = 1; 
                app.UpdateButton.Enable=1;
            else
                app.AttachCheckBox.Enable = 0;
                app.PORTDropDown.Enable = 0;
                app.UpdateButton.Enable=0;
                
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            stop(app.joyTimer)
            delete(app)
            
        end

        % Callback function
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            if value == "Arduino"
                app.uipanel2.Visible = 'on';
                app.SettingsPanel.Visible = 'off';
            end
            if value == "Xbox"
                app.uipanel2.Visible = 'off';
                app.SettingsPanel.Visible = 'on';
            end
            if value == "Off"
                app.uipanel2.Visible = 'off';
                app.SettingsPanel.Visible = 'off';  
                
            end
            
        end

        % Value changed function: Switch
        function SwitchValueChanged2(app, event)
            value = app.Switch.Value;
            
             if value == "On"
            app.Lamp_3.Color = 'y';
            RobotDKConnect(app,app.RobotnameEditField.Value);
             else
                app.Lamp_3.Color = 'r';
                clear RDK robot
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Axis1Panel = uipanel(app.UIFigure);
            app.Axis1Panel.Title = 'Axis 1';
            app.Slider = uislider(app.Axis1Panel);
            app.Slider.Limits = [-180 180];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Label_8 = uilabel(app.Axis1Panel);
            app.Label_8.Text = 'θ1:';
            app.Slider_2 = uislider(app.Axis1Panel);
            app.Slider_2.Limits = [-180 180];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Label_9 = uilabel(app.Axis1Panel);
            app.Label_9.Text = 'θ2:';
            app.Slider_3 = uislider(app.Axis1Panel);
            app.Slider_3.Limits = [-180 180];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Slider_3ValueChanging, true);
            app.Label_10 = uilabel(app.Axis1Panel);
            app.Label_10.Text = 'θ3:';
            app.Slider_5 = uislider(app.Axis1Panel);
            app.Slider_5.Limits = [-180 180];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Slider_5.ValueChangingFcn = createCallbackFcn(app, @Slider_5ValueChanging, true);
            app.Label_11 = uilabel(app.Axis1Panel);
            app.Label_11.Text = 'θ4:';
            app.Slider_6 = uislider(app.Axis1Panel);
            app.Slider_6.Limits = [-180 180];
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @Slider_6ValueChanged, true);
            app.Slider_6.ValueChangingFcn = createCallbackFcn(app, @Slider_6ValueChanging, true);
            app.Label_12 = uilabel(app.Axis1Panel);
            app.Label_12.Text = 'θ5:';
            app.Slider_7 = uislider(app.Axis1Panel);
            app.Slider_7.Limits = [-180 180];
            app.Slider_7.ValueChangedFcn = createCallbackFcn(app, @Slider_7ValueChanged, true);
            app.Slider_7.ValueChangingFcn = createCallbackFcn(app, @Slider_7ValueChanging, true);
            app.Label_13 = uilabel(app.Axis1Panel);
            app.Label_13.Text = 'θ6:';
            app.EditField = uieditfield(app.Axis1Panel, 'numeric');
            app.EditField.RoundFractionalValues = 'on';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.Axis1Panel, 'numeric');
            app.EditField_2.RoundFractionalValues = 'on';
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.Axis1Panel, 'numeric');
            app.EditField_3.RoundFractionalValues = 'on';
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4 = uieditfield(app.Axis1Panel, 'numeric');
            app.EditField_4.RoundFractionalValues = 'on';
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_5 = uieditfield(app.Axis1Panel, 'numeric');
            app.EditField_5.RoundFractionalValues = 'on';
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_6 = uieditfield(app.Axis1Panel, 'numeric');
            app.EditField_6.RoundFractionalValues = 'on';
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.dViewPanel = uipanel(app.UIFigure);
            app.dViewPanel.Title = '3d View';
            app.UIAxes2 = uiaxes(app.dViewPanel);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.LimitsSliderLabel = uilabel(app.dViewPanel);
            app.LimitsSliderLabel.Text = 'Limits';
            app.LimitsSlider = uislider(app.dViewPanel);
            app.LimitsSlider.Limits = [0 2000];
            app.LimitsSlider.ValueChangedFcn = createCallbackFcn(app, @LimitsSliderValueChanged, true);
            app.LimitsSlider.Value = 2000;
            app.TablePanel = uipanel(app.UIFigure);
            app.TablePanel.Title = 'Table';
            app.UITable = uitable(app.TablePanel);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.Panel_2 = uipanel(app.TablePanel);
            app.Label_18 = uilabel(app.Panel_2);
            app.Label_18.Text = 'θ1';
            app.Label_19 = uilabel(app.Panel_2);
            app.Label_19.Text = '-100';
            app.Label_20 = uilabel(app.Panel_2);
            app.Label_20.Text = 'θ2';
            app.Label_21 = uilabel(app.Panel_2);
            app.Label_21.Text = '-100';
            app.Label_22 = uilabel(app.Panel_2);
            app.Label_22.Text = 'θ3';
            app.Label_23 = uilabel(app.Panel_2);
            app.Label_23.Text = '-100';
            app.Label_24 = uilabel(app.Panel_2);
            app.Label_24.Text = 'θ4';
            app.Label_25 = uilabel(app.Panel_2);
            app.Label_25.Text = '-100';
            app.Label_26 = uilabel(app.Panel_2);
            app.Label_26.Text = 'θ5';
            app.Label_27 = uilabel(app.Panel_2);
            app.Label_27.Text = '-100';
            app.Label_28 = uilabel(app.Panel_2);
            app.Label_28.Text = 'θ6';
            app.Label_29 = uilabel(app.Panel_2);
            app.Label_29.Text = '-100';
            app.Button = uibutton(app.Panel_2, 'push');
            app.Button.Icon = 'Copy.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.Panel_2, 'push');
            app.Button_2.Icon = 'paste.png';
            app.Button_2.Text = '';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.TabGroup = uitabgroup(app.Panel);
            app.ArduinoTab = uitab(app.TabGroup);
            app.ArduinoTab.Title = 'Arduino';
            app.PORTDropDown = uidropdown(app.ArduinoTab);
            app.PORTDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4'};
            app.PORTDropDown.Editable = 'on';
            app.PORTDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PORTDropDownOpening, true);
            app.PORTDropDown.Enable = 'off';
            app.PORTDropDown.Placeholder = 'Select';
            app.PORTDropDown.Value = 'COM4';
            app.UpdateButton = uibutton(app.ArduinoTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Enable = 'off';
            app.UpdateButton.Text = 'Update';
            app.AttachCheckBox = uicheckbox(app.ArduinoTab);
            app.AttachCheckBox.ValueChangedFcn = createCallbackFcn(app, @AttachCheckBoxValueChanged2, true);
            app.AttachCheckBox.Enable = 'off';
            app.AttachCheckBox.Text = 'Attach';
            app.Lamp = uilamp(app.ArduinoTab);
            app.TraveltypeButtonGroup = uibuttongroup(app.ArduinoTab);
            app.TraveltypeButtonGroup.Title = 'Travel type:';
            app.JointbyjointButton = uiradiobutton(app.TraveltypeButtonGroup);
            app.JointbyjointButton.Text = 'Joint by joint';
            app.JointbyjointButton.Value = true;
            app.RobotbaseButton = uiradiobutton(app.TraveltypeButtonGroup);
            app.RobotbaseButton.Text = 'Robot base';
            app.ToolframeButton = uiradiobutton(app.TraveltypeButtonGroup);
            app.ToolframeButton.Text = 'Tool frame';
            app.SettingsPanel = uipanel(app.ArduinoTab);
            app.SettingsPanel.Title = 'Settings';
            app.DropDown = uidropdown(app.SettingsPanel);
            app.DropDown.Items = {'Theta 1', 'Theta 2', 'Theta 3', 'Theta 4', 'Theta 5', 'Theta 6'};
            app.DropDown.Value = 'Theta 1';
            app.X1Label = uilabel(app.SettingsPanel);
            app.X1Label.Text = 'X1:';
            app.Y1Label = uilabel(app.SettingsPanel);
            app.Y1Label.Text = 'Y1:';
            app.X2Label = uilabel(app.SettingsPanel);
            app.X2Label.Text = 'X2:';
            app.Y2Label = uilabel(app.SettingsPanel);
            app.Y2Label.Text = 'Y2:';
            app.DropDown_2 = uidropdown(app.SettingsPanel);
            app.DropDown_2.Items = {'Theta 1', 'Theta 2', 'Theta 3', 'Theta 4', 'Theta 5', 'Theta 6'};
            app.DropDown_2.Value = 'Theta 1';
            app.DropDown_3 = uidropdown(app.SettingsPanel);
            app.DropDown_3.Items = {'Theta 1', 'Theta 2', 'Theta 3', 'Theta 4', 'Theta 5', 'Theta 6'};
            app.DropDown_3.Value = 'Theta 1';
            app.DropDown_4 = uidropdown(app.SettingsPanel);
            app.DropDown_4.Items = {'Theta 1', 'Theta 2', 'Theta 3', 'Theta 4', 'Theta 5', 'Theta 6'};
            app.DropDown_4.Value = 'Theta 1';
            app.XboxTab = uitab(app.TabGroup);
            app.XboxTab.Title = 'Xbox';
            app.Lamp_2 = uilamp(app.XboxTab);
            app.X1Label_2 = uilabel(app.Panel);
            app.X1Label_2.Text = 'X1:';
            app.Y1Label_2 = uilabel(app.Panel);
            app.Y1Label_2.Text = 'Y1:';
            app.X2Label_2 = uilabel(app.Panel);
            app.X2Label_2.Text = 'X2:';
            app.Y2Label_2 = uilabel(app.Panel);
            app.Y2Label_2.Text = 'Y2:';
            app.Label_14 = uilabel(app.Panel);
            app.Label_14.Text = '-10';
            app.Label_15 = uilabel(app.Panel);
            app.Label_15.Text = '-10';
            app.Label_16 = uilabel(app.Panel);
            app.Label_16.Text = '-10';
            app.Label_17 = uilabel(app.Panel);
            app.Label_17.Text = '-10';
            app.RobotDKPanel = uipanel(app.UIFigure);
            app.RobotDKPanel.Title = 'Robot DK';
            app.RobotnameEditFieldLabel = uilabel(app.RobotDKPanel);
            app.RobotnameEditFieldLabel.Text = 'Robot name';
            app.RobotnameEditField = uieditfield(app.RobotDKPanel, 'text');
            app.Lamp_3 = uilamp(app.RobotDKPanel);
            app.Switch = uiswitch(app.RobotDKPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged2, true);
            app.ReadPoseButton = uibutton(app.RobotDKPanel, 'push');
            app.ReadPoseButton.Text = 'Read Pose';
            app.SetPoseButton = uibutton(app.RobotDKPanel, 'push');
            app.SetPoseButton.Text = 'Set Pose';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotUR5_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end