classdef RobotUR5_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        SelectRobotPanel    matlab.ui.container.Panel
        SelectButton        matlab.ui.control.Button
        DropDown            matlab.ui.control.DropDown
        ModelLabel          matlab.ui.control.Label
        TablePanel          matlab.ui.container.Panel
        UITable             matlab.ui.control.Table
        dViewPanel          matlab.ui.container.Panel
        LimitsSlider        matlab.ui.control.Slider
        LimitsSliderLabel   matlab.ui.control.Label
        UIAxes2             matlab.ui.control.UIAxes
        Axis1Panel          matlab.ui.container.Panel
        EditField_6         matlab.ui.control.NumericEditField
        EditField_5         matlab.ui.control.NumericEditField
        EditField_4         matlab.ui.control.NumericEditField
        EditField_3         matlab.ui.control.NumericEditField
        EditField_2         matlab.ui.control.NumericEditField
        EditField           matlab.ui.control.NumericEditField
        Label_13            matlab.ui.control.Label
        Slider_7            matlab.ui.control.Slider
        Label_12            matlab.ui.control.Label
        Slider_6            matlab.ui.control.Slider
        Label_11            matlab.ui.control.Label
        Slider_5            matlab.ui.control.Slider
        Label_10            matlab.ui.control.Label
        Slider_3            matlab.ui.control.Slider
        Label_9             matlab.ui.control.Label
        Slider_2            matlab.ui.control.Slider
        Label_8             matlab.ui.control.Label
        Slider              matlab.ui.control.Slider
        RobotDKPanel        matlab.ui.container.Panel
        ConnectCheckBox_2   matlab.ui.control.CheckBox
        NameEditField       matlab.ui.control.EditField
        NameEditFieldLabel  matlab.ui.control.Label
        uipanel2            matlab.ui.container.Panel
        Label_17            matlab.ui.control.Label
        Label_16            matlab.ui.control.Label
        Label_15            matlab.ui.control.Label
        Label_14            matlab.ui.control.Label
        Y2Label             matlab.ui.control.Label
        X2Label             matlab.ui.control.Label
        Y1Label             matlab.ui.control.Label
        X1Label             matlab.ui.control.Label
        StatusLabel         matlab.ui.control.Label
        Lamp                matlab.ui.control.Lamp
        AttachCheckBox      matlab.ui.control.CheckBox
        UpdateButton        matlab.ui.control.Button
        PORTDropDown        matlab.ui.control.DropDown
        PORTDropDownLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        t1 = 0 % Description
        t2 = 0  % Description
        t3 = 0 % Description
        t4 = 0 % Description
        t5 = 0  % Description
        t6 = 0 % Description
        joyMiddlePosX1 = 0;
        joyMiddlePosY1 = 0;
        joyMiddlePosX2 = 0;
        joyMiddlePosY2 = 0;
        joyX1
        joyY1
        joyX2
        joyY2
        
        joyUpdateTime=0.1 % Description
        joyTimer % Description
    end
    
    methods (Access = private)
        
        function initPorts(app)
            freeports = serialportlist("available");
            app.PORTDropDown.Items = freeports;
        end
        
        function results = RobotDKConnect(app,name)
            
            RDK=Robolink;
            robot=RDK.Item(name);
            
        end
        
        function enableSliders(app,enabled)
            app.Slider.Enable = enabled;
            app.Slider_2.Enable = enabled;
            app.Slider_3.Enable = enabled;
            app.Slider_5.Enable = enabled;
            app.Slider_6.Enable = enabled;
            app.Slider_7.Enable = enabled;
        end
        
        function results = RobotFunc(app)
           
            cla(app.UIAxes2)
           app.UIFigure.HandleVisibility = 'on';
           [T06] = UR5(app.t1,app.t2,app.t3,app.t4,app.t5,app.t6)
           app.UITable.Data = T06;
            hold(app.UIAxes2); 
            setLimits(app);
            
        end


        function setLimits(app)
            limit = app.LimitsSlider.Value;
             axis(app.UIAxes2,[-limit limit -limit limit -limit limit]);
        end
        
        function joyUpdate(app)
           
            errorX1 = app.joyMiddlePosX1 - (-readVoltage(a,'A0'))/2.5;
            errorY1 = app.joyMiddlePosY1 -(readVoltage(a,'A1'))/2.5;
            
            errorX2 = app.joyMiddlePosX2 - (-readVoltage(a,'A2'))/2.5;
            errorY2 = app.joyMiddlePosY2 - (-readVoltage(a,'A3'))/2.5;
            
            app.joyX1 = app.joyX1 - errorX1;
            app.joyY1 = app.joyY1 - errorY1;
            
            app.joyX2 = app.joyX2 - errorX2;
            app.joyY2 = app.joyY2 - errorY2;
            
            if (abs(errorX1)<0.15)
                errorX1 = 0;
            end
            if (abs(errorY1)<0.15)
                errorY1 = 0;
            end
            
            if (abs(errorX2)<0.15)
                errorX2 = 0;
            end
            if (abs(errorY2)<0.15)
                errorY2 = 0;
            end
            
            
            errorX1 = floor(errorX1*5);
            errorY1 = floor(errorY1*5);
            
            errorX2 = floor(errorX2*5);
            errorY2 = floor(errorY2*5);
            
            if  errorX1 > 3
               errorX1 = 3;  
            end
            if  errorX1 < -3
                errorX1 = -3;  
            end
            
            if  errorY1 > 3
               errorY1 = 3;  
            end
            if  errorY1 < -3
                errorY1 = -3;  
            end


            if  errorX2 > 3
               errorX2 = 3;  
            end
            if  errorX2 < -3
                errorX2 = -3;  
            end
            
            if  errorY2 > 3
               errorY2 = 3;  
            end
            if  errorY2 < -3
                errorY2 = -3;  
            end
            
            app.joyX1 = app.joyX1 - errorX1;
            app.joyY1 = app.joyY1 - errorY1;
            
            app.joyX2 = app.joyX2 - errorX2;
            app.joyY2 = app.joyY2 - errorY2;
            
            
             app.Label_14.Text = num2str(app.joyX1);
             app.Label_15.Text = num2str(app.joyY1);
             app.Label_16.Text = num2str(app.joyX2);
             app.Label_17.Text = num2str(app.joyY2);
            
        end
        
        function joyInit(app)
            app.joyMiddlePosX1 = -readVoltage(a,'A0')/2.5
            app.joyMiddlePosY1 = readVoltage(a,'A1')/2.5
            app.joyMiddlePosX2 = -readVoltage(a,'A2')/2.5
            app.joyMiddlePosY2 =  readVoltage(a,'A3')/2.5
            % таймер 
            
        end
        
        function timers(app)
           app.joyTimer = timer('Period',2,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
           
           app.joyTimer.TimerFcn = @(x,y)disp('do something');
           start(app.joyTimer)
            
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setLimits(app);
            initPorts(app);
            timers(app);
            global ard;
            global RDK;
            global robot;
           
            RobotFunc(app)


        end

        % Drop down opening function: PORTDropDown
        function PORTDropDownOpening(app, event)
       
        end

        % Callback function
        function AttachCheckBoxValueChanged(app, event)
            value = app.AttachCheckBox.Value;
            app.PORTDropDown.Enable = ~value;       
            
            if value == 1
                 ard = arduino(app.PORTDropDown.Value,"Uno");
                app.PORTDropDown.BackgroundColor = 'g';
                enableSliders(app,false);
                   
            while value == 1
                app.t1=floor((360*readVoltage(ard,'A0')/5)-180);
                   app.t2=floor((360*readVoltage(ard,'A1')/5)-180);
                   app.t3=floor((360*readVoltage(ard,'A2')/5)-180);
                   cla
                [T06] = UR5(app.t1,app.t2,app.t3,app.t4,app.t5,app.t6)
                    
                    app.Slider.Value = app.t1;
                    app.Slider_2.Value = app.t2;
                    app.Slider_3.Value = app.t3;
                    app.Slider_5.Value = app.t4;
                    app.Slider_6.Value = app.t5;
                    app.Slider_7.Value = app.t6;
                    
                    app.Label.Text = num2str(floor(app.t1));
                    app.Label_2.Text = num2str(floor(app.t2));
                    app.Label_3.Text = num2str(floor(app.t3));
                    app.Label_5.Text = num2str(floor(app.t4));
                    app.Label_6.Text = num2str(floor(app.t5));
                    app.Label_7.Text = num2str(floor(app.t6));
                        
            end
             clear a;
            else
                clear a;
                app.PORTDropDown.BackgroundColor = [0.96,0.96,0.96];
                enableSliders(app,true);
            end


        end

        % Value changed function: ConnectCheckBox_2
        function ConnectCheckBox_2ValueChanged(app, event)
            value = app.ConnectCheckBox_2.Value;
            app.NameEditField.Enable = ~value;
            if value == 1
            RobotDKConnect(app,app.NameEditField.Value);
            else
                clear RDK robot
            end
            
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField.Value = (floor(changingValue));
         
            app.t1=changingValue;
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = event.Value;
           app.EditField_2.Value = (floor(changingValue));
            app.t2=changingValue;
        end

        % Value changing function: Slider_3
        function Slider_3ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_3.Value = (floor(changingValue));
            app.t3=changingValue;
        end

        % Value changing function: Slider_5
        function Slider_5ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_4.Value = (floor(changingValue));
            app.t4=changingValue;
        end

        % Value changing function: Slider_6
        function Slider_6ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_5.Value = (floor(changingValue));
            app.t5=changingValue;
        end

        % Value changing function: Slider_7
        function Slider_7ValueChanging(app, event)
            changingValue = event.Value;
            app.EditField_6.Value = (floor(changingValue));
            app.t6=changingValue;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            RobotFunc(app)
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            RobotFunc(app)
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            value = app.Slider_3.Value;
            RobotFunc(app)
        end

        % Value changed function: Slider_5
        function Slider_5ValueChanged(app, event)
            value = app.Slider_5.Value;
            RobotFunc(app)
        end

        % Value changed function: Slider_7
        function Slider_7ValueChanged(app, event)
            value = app.Slider_7.Value;
            RobotFunc(app)
        end

        % Value changed function: Slider_6
        function Slider_6ValueChanged(app, event)
            value = app.Slider_6.Value;
            RobotFunc(app)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
        end

        % Value changed function: LimitsSlider
        function LimitsSliderValueChanged(app, event)
            limit = app.LimitsSlider.Value;
            axis([-limit limit -limit limit -limit limit]);
        end

        % Callback function
        function CoonectSwitchValueChanged(app, event)
            value= app.CoonectSwitch.Value;
           
            
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            initPorts(app);
        end

        % Value changed function: AttachCheckBox
        function AttachCheckBoxValueChanged2(app, event)
            value = app.AttachCheckBox.Value; 
            
           
            app.PORTDropDown.Enable = ~value;       
            
            if value == 1
                 joyInit(app)
                 ard = arduino(app.PORTDropDown.Value,"Uno");
                app.Lamp.Color = 'g';
                enableSliders(app,false);
                   
            while app.AttachCheckBox.Value == 1
                   app.t1=app.t1+floor((readVoltage(ard,'A0')/5)-2.5);
                   app.t2=app.t2+floor((readVoltage(ard,'A1')/5)-2.5);
                   app.t3=app.t3+floor((readVoltage(ard,'A2')/5)-2.5);
                   app.t4=app.t4+floor((readVoltage(ard,'A3')/5)-2.5);
                    
                   app.Label_14.Text = num2str(app.t1);
                   app.Label_15.Text = num2str(app.t2);
                   app.Label_16.Text = num2str(app.t3);
                   app.Label_17.Text = num2str(app.t4);
                   
                   RobotFunc(app)
                    
                    app.Slider.Value = app.t1;
                    app.Slider_2.Value = app.t2;
                    app.Slider_3.Value = app.t3;
                    app.Slider_5.Value = app.t4;
                    app.Slider_6.Value = app.t5;
                    app.Slider_7.Value = app.t6;
                    
                    app.EditField.Value = (floor(app.t1));
                    app.EditField_2.Value =  (floor(app.t2));
                    app.EditField_3.Value = (floor(app.t3));
                    app.EditField_4.Value = (floor(app.t4));
                    app.EditField_5.Value = (floor(app.t5));
                   app.EditField_6.Value = (floor(app.t6));


            end
             clear a;
            else
                clear a;
                app.Lamp.Color = [1.00,0,0];
                app.PORTDropDown.BackgroundColor = [0.96,0.96,0.96];
                enableSliders(app,true);
            end
         
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.Slider.Value = value;
            app.t1 = value;
            RobotFunc(app);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.Slider_2.Value = value;
            app.t2 = value;
            RobotFunc(app);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.Slider_3.Value = value;
            app.t3 = value;
            RobotFunc(app);
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            app.Slider_5.Value = value;
            app.t4 = value;
            RobotFunc(app);
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            app.Slider_6.Value = value;
            app.t5 = value;
            RobotFunc(app);
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
            app.Slider_7.Value = value;
            app.t6 = value;
            RobotFunc(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.uipanel2 = uipanel(app.UIFigure);
            app.uipanel2.Title = 'Arduino Remote Control:';
            app.uipanel2.Tag = 'uipanel2';
            app.PORTDropDownLabel = uilabel(app.uipanel2);
            app.PORTDropDownLabel.Text = 'PORT';
            app.PORTDropDown = uidropdown(app.uipanel2);
            app.PORTDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4'};
            app.PORTDropDown.Editable = 'on';
            app.PORTDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PORTDropDownOpening, true);
            app.PORTDropDown.Placeholder = 'Select';
            app.PORTDropDown.Value = 'COM4';
            app.UpdateButton = uibutton(app.uipanel2, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.AttachCheckBox = uicheckbox(app.uipanel2);
            app.AttachCheckBox.ValueChangedFcn = createCallbackFcn(app, @AttachCheckBoxValueChanged2, true);
            app.AttachCheckBox.Text = 'Attach';
            app.Lamp = uilamp(app.uipanel2);
            app.StatusLabel = uilabel(app.uipanel2);
            app.StatusLabel.Text = 'Status:';
            app.X1Label = uilabel(app.uipanel2);
            app.X1Label.Text = 'X1:';
            app.Y1Label = uilabel(app.uipanel2);
            app.Y1Label.Text = 'Y1:';
            app.X2Label = uilabel(app.uipanel2);
            app.X2Label.Text = 'X2:';
            app.Y2Label = uilabel(app.uipanel2);
            app.Y2Label.Text = 'Y2:';
            app.Label_14 = uilabel(app.uipanel2);
            app.Label_14.Text = '0';
            app.Label_15 = uilabel(app.uipanel2);
            app.Label_15.Text = '0';
            app.Label_16 = uilabel(app.uipanel2);
            app.Label_16.Text = '0';
            app.Label_17 = uilabel(app.uipanel2);
            app.Label_17.Text = '0';
            app.RobotDKPanel = uipanel(app.UIFigure);
            app.RobotDKPanel.Title = 'RobotDK:';
            app.NameEditFieldLabel = uilabel(app.RobotDKPanel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.RobotDKPanel, 'text');
            app.ConnectCheckBox_2 = uicheckbox(app.RobotDKPanel);
            app.ConnectCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ConnectCheckBox_2ValueChanged, true);
            app.ConnectCheckBox_2.Text = 'Connect';
            app.Axis1Panel = uipanel(app.UIFigure);
            app.Axis1Panel.Title = 'Axis 1';
            app.Slider = uislider(app.Axis1Panel);
            app.Slider.Limits = [-180 180];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Label_8 = uilabel(app.Axis1Panel);
            app.Label_8.Text = 'θ1:';
            app.Slider_2 = uislider(app.Axis1Panel);
            app.Slider_2.Limits = [-180 180];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Label_9 = uilabel(app.Axis1Panel);
            app.Label_9.Text = 'θ2:';
            app.Slider_3 = uislider(app.Axis1Panel);
            app.Slider_3.Limits = [-180 180];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Slider_3.ValueChangingFcn = createCallbackFcn(app, @Slider_3ValueChanging, true);
            app.Label_10 = uilabel(app.Axis1Panel);
            app.Label_10.Text = 'θ3:';
            app.Slider_5 = uislider(app.Axis1Panel);
            app.Slider_5.Limits = [-180 180];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Slider_5ValueChanged, true);
            app.Slider_5.ValueChangingFcn = createCallbackFcn(app, @Slider_5ValueChanging, true);
            app.Label_11 = uilabel(app.Axis1Panel);
            app.Label_11.Text = 'θ4:';
            app.Slider_6 = uislider(app.Axis1Panel);
            app.Slider_6.Limits = [-180 180];
            app.Slider_6.ValueChangedFcn = createCallbackFcn(app, @Slider_6ValueChanged, true);
            app.Slider_6.ValueChangingFcn = createCallbackFcn(app, @Slider_6ValueChanging, true);
            app.Label_12 = uilabel(app.Axis1Panel);
            app.Label_12.Text = 'θ5:';
            app.Slider_7 = uislider(app.Axis1Panel);
            app.Slider_7.Limits = [-180 180];
            app.Slider_7.ValueChangedFcn = createCallbackFcn(app, @Slider_7ValueChanged, true);
            app.Slider_7.ValueChangingFcn = createCallbackFcn(app, @Slider_7ValueChanging, true);
            app.Label_13 = uilabel(app.Axis1Panel);
            app.Label_13.Text = 'θ6:';
            app.EditField = uieditfield(app.Axis1Panel, 'numeric');
            app.EditField.RoundFractionalValues = 'on';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.Axis1Panel, 'numeric');
            app.EditField_2.RoundFractionalValues = 'on';
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.Axis1Panel, 'numeric');
            app.EditField_3.RoundFractionalValues = 'on';
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4 = uieditfield(app.Axis1Panel, 'numeric');
            app.EditField_4.RoundFractionalValues = 'on';
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_5 = uieditfield(app.Axis1Panel, 'numeric');
            app.EditField_5.RoundFractionalValues = 'on';
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_6 = uieditfield(app.Axis1Panel, 'numeric');
            app.EditField_6.RoundFractionalValues = 'on';
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.dViewPanel = uipanel(app.UIFigure);
            app.dViewPanel.Title = '3d View';
            app.UIAxes2 = uiaxes(app.dViewPanel);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.LimitsSliderLabel = uilabel(app.dViewPanel);
            app.LimitsSliderLabel.Text = 'Limits';
            app.LimitsSlider = uislider(app.dViewPanel);
            app.LimitsSlider.Limits = [0 2000];
            app.LimitsSlider.ValueChangedFcn = createCallbackFcn(app, @LimitsSliderValueChanged, true);
            app.LimitsSlider.Value = 2000;
            app.TablePanel = uipanel(app.UIFigure);
            app.TablePanel.Title = 'Table';
            app.UITable = uitable(app.TablePanel);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.SelectRobotPanel = uipanel(app.UIFigure);
            app.SelectRobotPanel.Title = 'Select Robot';
            app.ModelLabel = uilabel(app.SelectRobotPanel);
            app.ModelLabel.Text = 'Model: ';
            app.DropDown = uidropdown(app.SelectRobotPanel);
            app.SelectButton = uibutton(app.SelectRobotPanel, 'push');
            app.SelectButton.Text = 'Select';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotUR5_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end